classdef Transformer_UserInterface_Antonio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConnectButton          matlab.ui.control.Button
        PortDropDown           matlab.ui.control.DropDown
        BaudRateDropDownLabel  matlab.ui.control.Label
        BaudRateDropDown       matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
        AngleTable             matlab.ui.control.Table
        AngleTable_2           matlab.ui.control.Table
        ConfigDropDown         matlab.ui.control.DropDown
        PortDropDownLabel_2    matlab.ui.control.Label
        CommandEditFieldLabel  matlab.ui.control.Label
        CommandEditField       matlab.ui.control.EditField
    end

    properties (Access = public)
        serialConn;
        time=0;
        sample_time=0.01;
        datalength_rx=32;
        connectflag=0;
        matrix;
        CommandTable;%=uint8(zeros(181,24));
        %matrix = zeros(181,24);
        %CommandTable=uint8(matrix);
%         CommandTable=uint8([0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0;...
%                             10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10  10;...
%                             20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20  20;...
%                             30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30  30;...
%                             60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60  60;...
%                             90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90  90;...
%                             120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120  120;...
%                             150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150  150;...
%                             180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180  180;...
%                             180  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0;...
%                             0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0;...
%                             0  180  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0; ...
%                             0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0;...
%                             180  180  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0;...
%                             0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0  0;...
%                             ]); 
        
    end
    methods (Access = private)
        function serial_data_available(app,src,event)
            %app.Label.Text=fgetl(app.serialConn);
            %RxText = fscanf(app.serialConn);
            if(read(app.serialConn,1,"uint8")==165)
                if(read(app.serialConn,1,"uint8")==90)
                    c=read(app.serialConn,app.datalength_rx-2,"uint8");
                    %app.AngleTable.Data(:,1)=table([2 2 2 2 2 2 2 2 2 2 2 2  ]');
                    %app.AngleTable_2.Data(:,1)=table([2 2 2 2 2 2 2 2 2 2 2 2  ]');
                    app.AngleTable.Data(:,1)=table(c(1:12)');
                    app.AngleTable_2.Data(:,1)=table(c(13:24)');
                else
                    %flush(app.serialConn);
                end
            else
                %flush(app.serialConn);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AngleTable.RowName={'Node 0','Node 1','Node 2','Node 3','Node 4','Node 5','Node 6','Node 7','Node 8','Node 9','Node 10','Node 11'};
            app.AngleTable.ColumnName ={'Command','Angle'};
            app.AngleTable.Data=table(zeros(12,1),zeros(12,1));
            app.AngleTable_2.RowName={'Node 12','Node 13','Node 14','Node 15','Node 16','Node 17','Node 18','Node 19','Node 20','Node 21','Node 22','Node 23'};
            app.AngleTable_2.ColumnName ={'Command','Angle'};
            app.AngleTable_2.Data=table(zeros(12,1),zeros(12,1));
            serialPorts = instrhwinfo('serial');
            nPorts = length(serialPorts.SerialPorts);
            app.PortDropDown.Items=serialPorts.SerialPorts;
            
            app.matrix = zeros(181,24);
            i = 0;
            for i=0:180
                app.matrix(i+1,:)=i;
            end
            app.CommandTable=uint8(app.matrix);
            
            for i=1:size(app.CommandTable,1)
                command_index{i}=num2str(i-1);
            end
            app.ConfigDropDown.Items=command_index;
                   
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            openport=app.PortDropDown.Value;
            if strcmp(app.ConnectButton.Text,'Connect')
                obj1 = instrfind('Type', 'serial', 'Port', openport, 'Tag', '');
                if isempty(obj1)
                    if(app.connectflag==0)
                        app.serialConn = serialport(openport,str2num(app.BaudRateDropDown.Value),'TimeOut',1);
                        %app.connectflag=1;
                    end
                    configureCallback(app.serialConn,"byte",app.datalength_rx,@app.serial_data_available);
                    app.ConnectButton.Text='Disconnect';
                else
                    fclose(obj1);
                    app.serialConn = obj1(1);
                end
            elseif strcmp(app.ConnectButton.Text,'Disconnect')
                try
                    app.connectflag=1;
                    app.ConnectButton.Text='Connect';
                    configureCallback(app.serialConn,"off");
                    delete(instrfindall)
                catch e
                    errordlg(e.message);
                end
            end
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            %value = app.PortDropDown.Value;
            serialPorts = instrhwinfo('serial');
            nPorts = length(serialPorts.SerialPorts);
            app.PortDropDown.Items=serialPorts.SerialPorts;
        end

        % Value changed function: ConfigDropDown
        function ConfigDropDownValueChanged(app, event)
            value = app.ConfigDropDown.Value;
            command_index=str2num(value)+1;
            temp=app.CommandTable(command_index,:);
            app.AngleTable.Data(:,2)=table(temp(1:12)');
            app.AngleTable_2.Data(:,2)=table(temp(13:24)');        
            data=zeros(1,32);
            data(1)=165;
            data(2)=90;
            data(3:26)=temp;
            write(app.serialConn,data,"uint8");
        end

        % Value changed function: CommandEditField
        function CommandEditFieldValueChanged(app, event)
            value = app.CommandEditField.Value;
            
            %command_index=str2num(value);
            temp(1:12) = table2array(app.AngleTable.Data(:,2))';
            temp(13:24) = table2array(app.AngleTable_2.Data(:,2))';
            temp_init = temp;
            
            n = count(value,";")+1;
            cmnds = split(value,";");
            max_angle = 0;
            
            for i=1:n
                str_i = split(cmnds(i));
                n_i = size(str_i,1);
                a_i = str_i(n_i);
                angle_i = str2double(a_i{1});
                for j=1:n_i-1
                    str_j = str_i(j);
                    index_j = str2double(str_j{1})+1;
                    temp(index_j) = angle_i;
                    if abs(angle_i-temp_init(index_j))>max_angle
                        max_angle = abs(angle_i-temp_init(index_j));
                    end
                end
            end
            
            app.AngleTable.Data(:,2)=table(temp(1:12)');
            app.AngleTable_2.Data(:,2)=table(temp(13:24)');        
            temp_fin = temp;
            
            data=zeros(1,32);
            data(1)=165;
            data(2)=90;
            %data(3:26)=temp;
            
            temp_interm = temp_init;
            for step=0:5:max_angle
                for motor=1:24
                    if temp_interm(motor)~=temp_fin(motor)
                        temp_interm(motor)=temp_init(motor)+step*sign(temp_fin(motor)-temp_init(motor));
                    end
                end
                data(3:26) = temp_interm;
                write(app.serialConn,data,"uint8");
                disp(data(1:10))
                pause(0.5); %seconds
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.UIFigure);
            app.BaudRateDropDown.Items = {'115200', '230400', '460800'};
            app.BaudRateDropDown.Value = '115200';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.AngleTable = uitable(app.UIFigure);
            app.AngleTable.ColumnName = {'Column 1'; 'Column 2'; 'Column3'};
            app.AngleTable.RowName = {};
            app.AngleTable_2 = uitable(app.UIFigure);
            app.AngleTable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.AngleTable_2.RowName = {};
            app.ConfigDropDown = uidropdown(app.UIFigure);
            app.ConfigDropDown.Items = {};
            app.ConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigDropDownValueChanged, true);
            app.ConfigDropDown.Value = {};
            app.PortDropDownLabel_2 = uilabel(app.UIFigure);
            app.PortDropDownLabel_2.Text = 'Configuration';
            app.CommandEditFieldLabel = uilabel(app.UIFigure);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.UIFigure, 'text');
            app.CommandEditField.ValueChangedFcn = createCallbackFcn(app, @CommandEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transformer_UserInterface_Antonio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end